classdef histogram < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Button_2       matlab.ui.control.Button
        Label_5        matlab.ui.control.Label
        Res2Image      matlab.ui.control.Image
        Label_4        matlab.ui.control.Label
        TargetImage    matlab.ui.control.Image
        Button         matlab.ui.control.Button
        Label_3        matlab.ui.control.Label
        Label_2        matlab.ui.control.Label
        Label          matlab.ui.control.Label
        GrayImage      matlab.ui.control.Image
        ResImage       matlab.ui.control.Image
        OriginalImage  matlab.ui.control.Image
    end

    
    methods (Access = private)
        
        function NewImage = equalization(~, rgbImage)
            
            histgram = imhist(rgbImage);
            [h, w] = size(rgbImage);
            NewImage = zeros(h, w, 'uint8');
            s = zeros(256);
            s(1) = histgram(1);
        
            for t=2:256
                s(t) = s(t-1) + histgram(t);
            end
            
            for x = 1:w
                for y = 1:h
                    NewImage(y,x) = uint8(255*s(rgbImage(y,x)+1)/(w*h));
                end
            end
        end
        

        function histogram = gray_histogram(~, image)
            histogram = zeros(256, 1);
            [height, width] = size(image);

            for x = 1:width
                for y = 1:height
                    pixelValue = image(y, x) + 1;	
                    histogram(pixelValue) = histogram(pixelValue) + 1;
                end
            end
        end

        function draw_histogram1(app, histogram)
            % 获取灰度直方图坐标轴组件
            axesHandle = app.UIAxesHistgram;
            % 绘制直方图
            bar(axesHandle, histogram);

        end

        function draw_histogram2(app, histogram)
            % 获取灰度直方图坐标轴组件
            axesHandle = app.UIAxes_2;
            % 绘制直方图
            bar(axesHandle, histogram);

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Image clicked function: OriginalImage
        function OriginalImageClicked(app, event)
            [filename, pathname] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files (*.jpg, *.png, *.bmp)'; '*.*', 'All Files (*.*)'}, 'Select an image file');
        
            % Check if the user selected a file
            if isequal(filename, 0) || isequal(pathname, 0)
                disp('User canceled the operation');
            else
                imagePath = fullfile(pathname, filename);

                img = imread(imagePath);

                app.OriginalImage.ImageSource = img;
                
                gray_image = rgb2gray(img);
                app.GrayImage.ImageSource = cat(3, gray_image, gray_image, gray_image);
                histogram = gray_histogram(app, gray_image);
                draw_histogram1(app, histogram);
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            gray_image = rgb2gray(app.OriginalImage.ImageSource);
            image=equalization(app, gray_image);
            app.ResImage.ImageSource = cat(3,image,image,image);
            histogram = gray_histogram(app, rgb2gray(app.ResImage.ImageSource));
            draw_histogram2(app, histogram);
        end

        % Image clicked function: TargetImage
        function TargetImageClicked(app, event)
            [filename, pathname] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files (*.jpg, *.png, *.bmp)'; '*.*', 'All Files (*.*)'}, 'Select an image file');
        
            % Check if the user selected a file
            if isequal(filename, 0) || isequal(pathname, 0)
                disp('User canceled the operation');
            else
                imagePath = fullfile(pathname, filename);

                img = imread(imagePath);

                app.TargetImage.ImageSource = img;

            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            input_image = rgb2gray(app.OriginalImage.ImageSource);
            target_image = rgb2gray(app.TargetImage.ImageSource);
            target_histogram = gray_histogram(app, target_image);
            input_histogram = gray_histogram(app, input_image);
       
            % 获取累积直方图
            input_cumulative = cumsum(input_histogram) / sum(input_histogram);
            target_cumulative = cumsum(target_histogram) / sum(target_histogram);
        
            % 映射表
            mapping_table = zeros(256, 1, 'uint8');
        
            % 进行直方图匹配
            for i = 1:256
                [~, index] = min(abs(target_cumulative - input_cumulative(i)));
                mapping_table(i) = index - 1;
            end
        
            % 应用映射表到输入图像
            matched_image = intlut(input_image, mapping_table);

            app.Res2Image.ImageSource = cat(3, matched_image, matched_image, matched_image);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OriginalImage = uiimage(app.UIFigure);
            app.OriginalImage.ImageClickedFcn = createCallbackFcn(app, @OriginalImageClicked, true);
            app.ResImage = uiimage(app.UIFigure);
            app.GrayImage = uiimage(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '原始图像';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '直方图均衡化';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '灰度图';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '直方图均衡化';
            app.TargetImage = uiimage(app.UIFigure);
            app.TargetImage.ImageClickedFcn = createCallbackFcn(app, @TargetImageClicked, true);
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '规定化参照图片';
            app.Res2Image = uiimage(app.UIFigure);
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '规定化结果';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '直方图匹配';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = histogram

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end